classdef bytepipe_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ClearButton                    matlab.ui.control.Button
        SendButton                     matlab.ui.control.Button
        CliCmdString                   matlab.ui.control.EditField
        CommandEditFieldLabel          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        AdrvTemp                       matlab.ui.control.NumericEditField
        TempCLabel                     matlab.ui.control.Label
        SerialPort                     matlab.ui.control.DropDown
        SerialPortLabel                matlab.ui.control.Label
        Logo_2                         matlab.ui.control.Image
        Address                        matlab.ui.control.EditField
        IPAddressLabel                 matlab.ui.control.Label
        Logo                           matlab.ui.control.Image
        ConnectButton                  matlab.ui.control.Button
        LogText                        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        ConfigurationTab               matlab.ui.container.Tab
        FileSystemTextArea             matlab.ui.control.TextArea
        FileSystemTextAreaLabel        matlab.ui.control.Label
        Adrv9001ConfigurationLabel     matlab.ui.control.Label
        ConfigurationSettings          matlab.ui.control.Table
        FilterTab                      matlab.ui.container.Tab
        FilterDesignerPanel            matlab.ui.container.Panel
        FirAmplitudeUnit               matlab.ui.control.DropDown
        FirResponseType                matlab.ui.control.DropDown
        FirDesignButton                matlab.ui.control.Button
        FirOrderEditField              matlab.ui.control.NumericEditField
        OrderEditFieldLabel            matlab.ui.control.Label
        FirFrequencyUnit               matlab.ui.control.DropDown
        FirFsEditField                 matlab.ui.control.NumericEditField
        FsEditFieldLabel               matlab.ui.control.Label
        FirFpassEditField              matlab.ui.control.NumericEditField
        FpassEditFieldLabel            matlab.ui.control.Label
        FirFstopEditField              matlab.ui.control.NumericEditField
        FstopEditFieldLabel            matlab.ui.control.Label
        FirApassEditField              matlab.ui.control.NumericEditField
        ApassEditFieldLabel            matlab.ui.control.Label
        FirAstopEditField              matlab.ui.control.NumericEditField
        AstopEditFieldLabel            matlab.ui.control.Label
        FirDesignMethodDropDown        matlab.ui.control.DropDown
        Adrv9001Panel                  matlab.ui.container.Panel
        PFirWriteButton                matlab.ui.control.Button
        PFirReadButton                 matlab.ui.control.Button
        PFirChannel                    matlab.ui.control.DropDown
        PFirPort                       matlab.ui.control.DropDown
        PFirCoefficientSetButtonGroup  matlab.ui.container.ButtonGroup
        PFirDButton                    matlab.ui.control.RadioButton
        PFirCButton                    matlab.ui.control.RadioButton
        PFirBButton                    matlab.ui.control.RadioButton
        PFirAButton                    matlab.ui.control.RadioButton
        PFirPlot                       matlab.ui.control.UIAxes
        Tx1Tab                         matlab.ui.container.Tab
        PhyStreamPanel                 matlab.ui.container.Panel
        Tx1EnableStream                matlab.ui.control.CheckBox
        Tx1Continuous                  matlab.ui.control.CheckBox
        Tx1DataSource                  matlab.ui.control.DropDown
        DataSourceLabel                matlab.ui.control.Label
        Tx1RadioState                  matlab.ui.control.DropDown
        RadioStateDropDownLabel_3      matlab.ui.control.Label
        Tx1PlotConfig                  matlab.ui.container.ButtonGroup
        Dpd1SubPlotsButton             matlab.ui.control.RadioButton
        Dpd1RxTxPhaseButton            matlab.ui.control.RadioButton
        Dpd1AMPMButton                 matlab.ui.control.RadioButton
        Dpd1AMAMButton                 matlab.ui.control.RadioButton
        Dpd1IQButton                   matlab.ui.control.RadioButton
        Dpd1SpectrumButton             matlab.ui.control.RadioButton
        Tx1SettingsTable               matlab.ui.control.Table
        Tx1Plot                        matlab.ui.control.UIAxes
        Tx2Tab                         matlab.ui.container.Tab
        Tx2RadioState                  matlab.ui.control.DropDown
        RadioStateDropDownLabel_4      matlab.ui.control.Label
        Tx2PlotConfig                  matlab.ui.container.ButtonGroup
        Dpd2SubPlotsButton             matlab.ui.control.RadioButton
        Dpd2RxTxPhaseButton            matlab.ui.control.RadioButton
        Dpd2AMPMButton                 matlab.ui.control.RadioButton
        Dpd2AMAMButton                 matlab.ui.control.RadioButton
        Dpd2IQButton                   matlab.ui.control.RadioButton
        Dpd2SpectrumButton             matlab.ui.control.RadioButton
        Tx2SettingsTable               matlab.ui.control.Table
        Tx2Plot                        matlab.ui.control.UIAxes
        Rx1Tab                         matlab.ui.container.Tab
        Rx1RadioState                  matlab.ui.control.DropDown
        RadioStateDropDownLabel        matlab.ui.control.Label
        Rx1PlotConfig                  matlab.ui.container.ButtonGroup
        Rx1SubPlotsButton              matlab.ui.control.RadioButton
        Rx1IQButton                    matlab.ui.control.RadioButton
        Rx1SpectrumButton              matlab.ui.control.RadioButton
        Rx1SettingsTable               matlab.ui.control.Table
        Rx1Plot                        matlab.ui.control.UIAxes
        Rx2Tab                         matlab.ui.container.Tab
        Rx2RadioState                  matlab.ui.control.DropDown
        RadioStateDropDownLabel_2      matlab.ui.control.Label
        Rx2PlotConfig                  matlab.ui.container.ButtonGroup
        Rx2SubPlotsButton              matlab.ui.control.RadioButton
        Rx2IQButton                    matlab.ui.control.RadioButton
        Rx2SpectrumButton              matlab.ui.control.RadioButton
        Rx2SettingsTable               matlab.ui.control.Table
        Rx2Plot                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        rfl;
        PFirDesignCoeff;
        rxTimer;
        tempTimer;
        TxSettings;
        DpdSettings;
    end


    methods (Access = private)

        function RflanCallback( app, str)
            app.App_UpdateLogText( char(str) );
        end

        function App_UpdateLogText( app, s )
            if strcmp(app.LogText.Value,'')
                message_disp = s;
            else
                message_disp = [app.LogText.Value(:)', {s}];
            end
            app.LogText.Value = message_disp;
            drawnow
            scroll(app.LogText, 'bottom');
            drawnow
        end

        function APP_PlotTx( app, channel )


            if( channel == app.rpmsg.CHANNEL_1)
                fs = app.Tx1SampleRate.Value;
                iq = app.rpmsg.iqTx1.double;

            else
                fs = app.Tx2SampleRate.Value;
                iq = app.rpmsg.iqTx2.double;

            end
            idata = iq(1:2:end)/2^15;
            qdata = iq(2:2:end)/2^15;
            iq = idata + 1i*qdata;

            bins = 1024;
            h2 = spectrum.welch('Hamming',bins);
            spec = msspectrum(h2,iq,...
                'Fs',fs,...
                'SpectrumType','twosided',...
                'CenterDC',true);
            f = spec.Frequencies/1e6;
            a = 10*log10(spec.Data);
            a(length(a)/2-2:length(a)/2+2) = a(length(a)/2-3);

            plot(app.TxPlot, f,a);
            app.TxPlot.XLabel.String = 'Frequency (MHz)';
            app.TxPlot.YLabel.String = 'Power (dB)';

        end

        function APP_PlotRx( app )
            fs = app.Rx1SampleRate.Value;
            iq = app.rpmsg.iqRx1;


            bins = 1024;
            h2 = spectrum.welch('Hamming',bins);
            spec = msspectrum(h2,iq,...
                'Fs',fs,...
                'SpectrumType','twosided',...
                'CenterDC',true);
            f1 = spec.Frequencies/1e6;
            a1 = 10*log10(spec.Data);


            fs = app.Rx2SampleRate.Value;
            iq = app.rpmsg.iqRx2;
            bins = 1024;
            h2 = spectrum.welch('Hamming',bins);
            spec = msspectrum(h2,iq,...
                'Fs',fs,...
                'SpectrumType','twosided',...
                'CenterDC',true);
            f2 = spec.Frequencies/1e6;
            a2 = 10*log10(spec.Data);


            plot(app.RxPlot, [f1,f2],[a1,a2]);

            app.RxPlot.XLabel.String = 'Frequency (MHz)';
            app.RxPlot.YLabel.String = 'Power (dB)';

        end

        function App_RxTimerCb( app, obj, event )
            app.rpmsg.Adrv9001_DataCapture( );
            app.APP_PlotRx( );
        end

        function App_TempTimerCb( app, obj, event )
            app.AdrvTemp.Value = app.rfl.GetTemp( );
        end

        function AppUpdateTxSettingsTable( app, Port )

            stop(app.tempTimer);

            Parameter = {'State'; ...
                'Frequency';...
                'SampleRate';...
                'Attenuation';...
                'BoostEnabled';...
                'ExtLoopBackPower';...
                'ExtPathDelay';...
                'ExtAmplifierType';...
                'DpdEnabled';...
                'DpdLutSize';...
                'DpdModel';...
                'DpdChangeModelTapOrders';...
                'DpdClgcEnable';...
                'DpdPreLutScale';...
                'DpdNumberofSamples';...
                'DpdAdditionalPowerScale';...
                'DpdCountsLessThanPowerThreshold';...
                'DpdCountsGreaterThanPeakThreshold';...
                'DpdImmediateLutSwitching';...
                'DpdUseSpecialFrame';...
                'DpdResetLuts';...
                'DpdSamplingRate';...
                'DpdRxTxNormalizationLowerThreshold';...
                'DpdRxTxNormalizationUpperThreshold';...
                'DpdDetectionPowerThreshold';...
                'DpdActivationThreshold';...
                'DpdTimeFilterCoefficient';...
                'DpdClgcLoopOpen';...
                'DpdCLGCFilterAlpha';...
                'DpdCLCGGainTarget';...
                'DpdCLGCLastGain';...
                'DpdCLCGFilteredGain';...
                'DPDCoefficients'};

            Unit = {'';...      % State
                'MHz';...   % Frequency
                'MHz';...   % SampleRate
                'dB';...    % Attenuation
                '';...      % BoostEnabled
                'dBm';...   % LoopBackPower
                'ps';...    % ExternalPathDelay
                '';...      % AmplifierType
                '';...      % DPD Enabled
                '';...      % LutSize
                '';...      % DpdModel
                '';...      % ChangeModelTapOrders
                '';...      % ClgcEnable
                '';...      % PreLutScale
                '';...      % NumberofSamples
                '';...      % AdditionalPowerScale
                '';...      % CountsLessThanPowerThreshold
                '';...      % CountsGreaterThanPeakThreshold
                '';...      % ImmediateLutSwitching
                '';...      % UseSpecialFrame
                '';...      % ResetLuts
                'MHz';...   % DpdSamplingRate
                'dBm';...   % RxTxNormalizationLowerThreshold
                'dBm';...   % RxTxNormalizationUpperThreshold
                'dBm';...   % DetectionPowerThreshold
                'dBm';...   % DetectionPeakThreshold
                '';...      % TimeFilterCoefficient
                '';...      % ClgcLoopOpen
                '';...      % CLGCFilterAlpha
                '';...      % CLCGGainTarget
                '';...      % CLGCLastGain
                '';...      % CLCGFilteredGain
                ''};        % DPDCoefficients


            Value = {app.rfl.GetRadioState( Port );...
                app.rfl.GetCarrierFrequency(Port)/1e6;...
                app.rfl.GetSampleRate(Port)/1e6;...
                app.rfl.GetTxAttn(Port);...
                app.rfl.GetTxBoost(Port);...
                app.rfl.GetExternalLoopbackPower(Port);...
                app.rfl.GetExternalLoopbackDelay(Port);...
                app.rfl.GetDpdAmplifierType(Port);...
                app.rfl.GetDpdEnable(Port);...
                app.rfl.GetDpdLutSize(Port);...
                app.rfl.GetDpdModel(Port);...
                app.rfl.GetDpdChangeModelTapOrders(Port);...
                app.rfl.GetDpdClgcEnable(Port);...
                app.rfl.GetDpdPreLutScale(Port);...
                app.rfl.GetDpdNumberofSamples(Port);...
                app.rfl.GetDpdAdditionalPowerScale(Port);...
                app.rfl.GetDpdCountsLessThanPowerThreshold(Port);...
                app.rfl.GetDpdCountsGreaterThanPeakThreshold(Port);...
                app.rfl.GetDpdImmediateLutSwitching(Port);...
                app.rfl.GetDpdUseSpecialFrame(Port);...
                app.rfl.GetDpdResetLuts(Port);...
                app.rfl.GetDpdSamplingRate_Hz(Port)/1e6;...
                app.rfl.GetDpdRxTxNormalizationLowerThreshold_dBm(Port);...
                app.rfl.GetDpdRxTxNormalizationUpperThreshold_dBm(Port);...
                app.rfl.GetDpdDetectionPowerThreshold_dBm(Port);...
                app.rfl.GetDpdDetectionPeakThreshold_dBm(Port);...
                app.rfl.GetDpdTimeFilterCoefficient(Port);...
                app.rfl.GetDpdClgcLoopOpen(Port);...
                app.rfl.GetDpdClgcFilterAlpha(Port);...
                app.rfl.GetDpdClgcGainTarget_dB(Port);...
                app.rfl.GetDpdClgcLastGain_dB(Port);...
                app.rfl.GetDpdClgcFilteredGain_dB(Port);...
                ' '};

            t = table(Parameter,Value,Unit);

            if( strcmp( Port, app.rfl.Tx1 ) )
                app.Tx1SettingsTable.Data = t;
                app.Tx1SettingsTable.ColumnName = t.Properties.VariableNames;
                app.Tx1RadioState.Value = Value{1};
            else
                app.Tx2SettingsTable.Data = t;
                app.Tx2SettingsTable.ColumnName = t.Properties.VariableNames;
                app.Tx2RadioState.Value = Value{1};
            end

            start(app.tempTimer);

        end

        function UpdateFileSystem( app )
            v = app.rfl.GetFileList( [] );
            app.FileSystemTextArea.Value = v;

            v = app.rfl.GetFileList( '.csv' );
            app.Tx1DataSource.Items = v;


        end

        function AppUpdateConfigSettingsTable( app )

            stop(app.tempTimer);

            Parameter = {'Hardware';...
                'Baseband Processor';...
                'RF Tranceiver';...
                'AdrvSiliconVersion'; ...
                'AdrvFirmwareVersion';...
                'AdrvAPIVersion'};

            Value = {'BytePipe_x9002';...
                'xazu3eg-sbva484-1-e';...
                'adrv9002';...
                app.rfl.GetAdrvSiliconVersion( );...
                app.rfl.GetAdrvFirmwareVersion( );...
                app.rfl.GetAdrvAPIVersion( )};

            t = table(Parameter,Value);

            app.ConfigurationSettings.Data = t;
            app.ConfigurationSettings.ColumnName = t.Properties.VariableNames;

            start(app.tempTimer);

        end


        function AppUpdateRxSettingsTable( app, Port )

            stop(app.tempTimer);

            Parameter = {'State'; ...
                'Frequency';...
                'IfFrequency';...
                'SampleRate';...
                'GainIndex';...
                'Rssi'};

            Unit = {'';...  % State
                'MHz';...   % Frequency
                'MHz';...   % IfFrequency
                'MHz';...   % SampleRate
                '';...      % Gain Index
                'dBm'};     % RSSI}


            Value = {app.rfl.GetRadioState( Port );...
                app.rfl.GetCarrierFrequency(Port)/1e6;...
                0;...
                app.rfl.GetSampleRate(Port)/1e6;...
                app.rfl.GetRxGain(Port);...
                app.rfl.GetRssi(Port)};

            t = table(Parameter,Value,Unit);

            if( strcmp( Port, app.rfl.Rx1 ) )
                app.Rx1SettingsTable.Data = t;
                app.Rx1SettingsTable.ColumnName = t.Properties.VariableNames;
                app.Rx1RadioState.Value = Value{1};
            else
                app.Rx2SettingsTable.Data = t;
                app.Rx2SettingsTable.ColumnName = t.Properties.VariableNames;
                app.Rx2RadioState.Value = Value{1};
            end

            start(app.tempTimer);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.SerialPort.Items = serialportlist;

            listOfTimers = timerfindall;
            if ~isempty(listOfTimers)
                delete(listOfTimers(:));
            end

            app.rfl = rflan( );
            app.rfl.SetCallback( @RflanCallback );

            app.rxTimer = timer('Period',1,'ExecutionMode','fixedSpacing','TasksToExecute',Inf);
            app.rxTimer.TimerFcn = @app.App_RxTimerCb;

            app.tempTimer = timer('Period',5,'ExecutionMode','fixedSpacing','TasksToExecute',Inf);
            app.tempTimer.TimerFcn = @app.App_TempTimerCb;


        end

        % Button pushed function: ConnectButton
        function ConnectBtn(app, event)
            app.rfl.Open( app.SerialPort.Value );
            app.rfl.SetCallback( @app.RflanCallback );

            AdrvSiliconVersion = app.rfl.GetAdrvSiliconVersion( );
            AdrvFirmwareVersion = app.rfl.GetAdrvFirmwareVersion( );
            AdrvAPIVersion = app.rfl.GetAdrvAPIVersion( );


            start(app.tempTimer);

            if( ~isempty( AdrvSiliconVersion ))
                app.App_UpdateLogText('Connected');
            else
                app.App_UpdateLogText('Failed to Connect');
            end
        end

        % Button pushed function: PFirReadButton
        function PFirReadBtn(app, event)

            if( app.profileValid )
                if( strcmp(app.PFirPort.Value,'PORT_RX') )
                    port = app.rpmsg.PORT_RX;
                else
                    port = app.rpmsg.PORT_TX;
                end

                if( strcmp(app.PFirChannel.Value, 'CHANNEL_1'))
                    channel = app.rpmsg.CHANNEL_1;
                else
                    channel = app.rpmsg.CHANNEL_2;
                end

                if( app.PFirAButton.Value )
                    coeff_set = 'A';
                elseif( app.PFirBButton.Value )
                    coeff_set = 'B';
                elseif( app.PFirCButton.Value )
                    coeff_set = 'C';
                else
                    coeff_set = 'D';
                end

                [x,y] = app.Rflan_GetPFirResponse( port, channel, coeff_set);

                fs = app.Rflan_GetSampleRate(port,channel);

                if( strcmp(app.FirFrequencyUnit.Value, 'MHz') )
                    x = x / 1e6;
                    fs = fs / 1e6;
                    app.PFirPlot.XLabel.String = 'Frequency (MHz)';
                elseif( strcmp(app.FirFrequencyUnit.Value, 'KHz') )
                    x = x / 1e3;
                    fs = fs / 1e3;
                    app.PFirPlot.XLabel.String = 'Frequency (KHz)';
                else
                    app.PFirPlot.XLabel.String = 'Frequency (Hz)';
                end

                plot(app.PFirPlot, x, y);
                grid on;
                app.PFirPlot.YLabel.String = 'Magnitude (dB)';

                app.FirFsEditField.Value = fs;

            else
                app.UpdateLogText(' Invalid Profile ');

            end
        end

        % Button pushed function: PFirWriteButton
        function PFirWriteCBtn(app, event)
            if( strcmp(app.PFirPort.Value,'PORT_RX') )
                port = app.rpmsg.PORT_RX;
            else
                port = app.rpmsg.PORT_TX;
            end

            if( strcmp(app.PFirChannel.Value, 'CHANNEL_1'))
                channel = app.rpmsg.CHANNEL_1;
            else
                channel = app.rpmsg.CHANNEL_2;
            end

            if( app.PFirAButton.Value )
                coeff_set = 'A';
            elseif( app.PFirBButton.Value )
                coeff_set = 'B';
            elseif( app.PFirCButton.Value )
                coeff_set = 'C';
            else
                coeff_set = 'D';
            end

            app.rpmsg.Adrv9001_SetPFirCoefficients( port, coeff_set, app.PFirDesignCoeff);
        end

        % Button pushed function: FirDesignButton
        function FirDesignBtn(app, event)
            resp = app.FirResponseType.Value;
            fpass = app.FirFpassEditField.Value;
            fstop = app.FirFstopEditField.Value;
            apass = app.FirApassEditField.Value;
            astop = app.FirAstopEditField.Value;
            fs = app.FirFsEditField.Value;

            if( strcmp(app.FirFrequencyUnit.Value, 'MHz'))
                fpass = fpass * 1e6;
                fstop = fstop*1e6;
                fs = fs * 1e6;
            elseif( strcmp(app.FirFrequencyUnit.Value, 'KHz'))
                fpass = fpass * 1e3;
                fstop = fstop *1e3;
                fs = fs*1e3;
            end

            d = designfilt(resp, ...
                'PassbandFrequency',fpass, ...
                'StopbandFrequency',fstop, ...
                'PassbandRipple',apass, ...
                'StopbandAttenuation',astop, ...
                'DesignMethod',app.FirDesignMethodDropDown.Value,...
                'SampleRate',fs);

            app.PFirDesignCoeff = d.Coefficients;

            h = freqz(d);
            h = 20*log10(abs(h));
            y = h;
            x = [0:fs/2/length(h):fs/2-1];
            x = double(x);

            if( strcmp(app.FirFrequencyUnit.Value, 'MHz') )
                x = x / 1e6;
                app.PFirPlot.XLabel.String = 'Frequency (MHz)';
            elseif( strcmp(app.FirFrequencyUnit.Value, 'KHz') )
                x = x / 1e3;
                app.PFirPlot.XLabel.String = 'Frequency (KHz)';
            else
                app.PFirPlot.XLabel.String = 'Frequency (Hz)';
            end

            plot(app.PFirPlot, x, y);
            grid on;
            app.PFirPlot.YLabel.String = 'Magnitude (dB)';

        end

        % Close request function: UIFigure
        function CloseAppCb(app, event)
            stop(app.tempTimer);

            app.rfl.Close();

            delete(app)

        end

        % Button pushed function: SendButton
        function CommandSendBtn(app, event)
            app.rfl.Write( app.CliCmdString.Value );
        end

        % Button pushed function: ClearButton
        function ClearBtn(app, event)
            app.LogText.Value = '';
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            if( strcmp(selectedTab.Title,'Rx1' ))
                app.AppUpdateRxSettingsTable( app.rfl.Rx1 );
            elseif( strcmp(selectedTab.Title,'Rx2' ))
                app.AppUpdateRxSettingsTable( app.rfl.Rx2 );
            elseif( strcmp(selectedTab.Title,'Tx1' ))
                app.AppUpdateTxSettingsTable( app.rfl.Tx1 );
                app.UpdateFiles( );
            elseif( strcmp(selectedTab.Title,'Tx2' ))
                app.AppUpdateTxSettingsTable( app.rfl.Tx2 );
            end

        end

        % Drop down opening function: SerialPort
        function SerialPortBtn(app, event)
            app.SerialPort.Items = serialportlist;
        end

        % Cell edit callback: Tx1SettingsTable
        function Tx1CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            switch indices(1)
                case 2
                    app.rfl.SetCarrierFrequency( app.rfl.Tx1, newData*1e6 );
                case 4
                    app.rfl.SetTxAttn( app.rfl.Tx1, newData );
                case 5
                    app.rfl.SetTxBoost( app.rfl.Tx1, newData );

                otherwise
                    app.App_UpdateLogText("Update not supported");
            end

        end

        % Cell edit callback: Tx2SettingsTable
        function Tx2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            switch indices(1)
                case 2
                    app.rfl.SetCarrierFrequency( app.rfl.Tx2, newData*1e6 );
                case 4
                    app.rfl.SetTxAttn( app.rfl.Tx2, newData );
                case 5
                    app.rfl.SetTxBoost( app.rfl.Tx2, newData );

                otherwise
                    app.App_UpdateLogText("Update not supported");
            end
        end

        % Value changed function: Tx2RadioState
        function Tx2RadioStateChange(app, event)
            value = app.Tx2RadioState.Value;
            app.rfl.SetRadioState(app.rfl.Tx2, value);
            pause(1);
            app.AppUpdateTxSettingsTable( app.rfl.Tx2 );

        end

        % Value changed function: Tx1RadioState
        function Tx1RadioStateChange(app, event)
            value = app.Tx1RadioState.Value;
            app.rfl.SetRadioState(app.rfl.Tx1, value);
            pause(1);
            app.AppUpdateTxSettingsTable( app.rfl.Tx1 );

        end

        % Value changed function: Tx1EnableStream
        function Tx1EnableStreamChceckBox(app, event)
            value = app.Tx1EnableStream.Value;

            if( value )
                if( app.Tx1Continuous )
                    SampleCnt = -1;
                else
                    SampleCnt = 0;
                end

                %                 app.rfl.PhyTxReq(app.rfl.Tx1, SampleCnt );
            end
        end

        % Value changed function: Tx1DataSource
        function Tx1DataSrcDropDown(app, event)
            value = app.Tx1DataSource.Value;

            if( strcmp(value, 'File') )


            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseAppCb, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.ConfigurationSettings = uitable(app.ConfigurationTab);
            app.ConfigurationSettings.ColumnName = '';
            app.ConfigurationSettings.RowName = {};
            app.ConfigurationSettings.ColumnEditable = true;
            app.Adrv9001ConfigurationLabel = uilabel(app.ConfigurationTab);
            app.Adrv9001ConfigurationLabel.Text = 'Adrv9001 Configuration';
            app.FileSystemTextAreaLabel = uilabel(app.ConfigurationTab);
            app.FileSystemTextAreaLabel.Text = 'File System';
            app.FileSystemTextArea = uitextarea(app.ConfigurationTab);
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.PFirPlot = uiaxes(app.FilterTab);
            title(app.PFirPlot, ' ')
            xlabel(app.PFirPlot, 'X')
            ylabel(app.PFirPlot, 'Y')
            zlabel(app.PFirPlot, 'Z')
            app.Adrv9001Panel = uipanel(app.FilterTab);
            app.Adrv9001Panel.Title = 'Adrv9001';
            app.PFirCoefficientSetButtonGroup = uibuttongroup(app.Adrv9001Panel);
            app.PFirCoefficientSetButtonGroup.Title = 'Coefficient Set';
            app.PFirAButton = uiradiobutton(app.PFirCoefficientSetButtonGroup);
            app.PFirAButton.Text = 'PFir A';
            app.PFirAButton.Value = true;
            app.PFirBButton = uiradiobutton(app.PFirCoefficientSetButtonGroup);
            app.PFirBButton.Text = 'PFir B';
            app.PFirCButton = uiradiobutton(app.PFirCoefficientSetButtonGroup);
            app.PFirCButton.Text = 'PFir C';
            app.PFirDButton = uiradiobutton(app.PFirCoefficientSetButtonGroup);
            app.PFirDButton.Text = 'PFir D';
            app.PFirPort = uidropdown(app.Adrv9001Panel);
            app.PFirPort.Items = {'PORT_RX', 'PORT_TX'};
            app.PFirPort.Value = 'PORT_RX';
            app.PFirChannel = uidropdown(app.Adrv9001Panel);
            app.PFirChannel.Items = {'CHANNEL_1', 'CHANNEL_2'};
            app.PFirChannel.Value = 'CHANNEL_1';
            app.PFirReadButton = uibutton(app.Adrv9001Panel, 'push');
            app.PFirReadButton.ButtonPushedFcn = createCallbackFcn(app, @PFirReadBtn, true);
            app.PFirReadButton.Text = 'Read';
            app.PFirWriteButton = uibutton(app.Adrv9001Panel, 'push');
            app.PFirWriteButton.ButtonPushedFcn = createCallbackFcn(app, @PFirWriteCBtn, true);
            app.PFirWriteButton.Text = 'Write';
            app.FilterDesignerPanel = uipanel(app.FilterTab);
            app.FilterDesignerPanel.Title = 'Filter Designer';
            app.FirDesignMethodDropDown = uidropdown(app.FilterDesignerPanel);
            app.FirDesignMethodDropDown.Items = {'butter', 'cheby1', 'cheby2', 'cls', 'ellip', 'equiripple', 'freqsamp', 'kaiserwin', 'ls', 'maxflat', 'window'};
            app.FirDesignMethodDropDown.Value = 'equiripple';
            app.AstopEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.AstopEditFieldLabel.Text = 'Astop';
            app.FirAstopEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.ApassEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.ApassEditFieldLabel.Text = 'Apass';
            app.FirApassEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FstopEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.FstopEditFieldLabel.Text = 'Fstop';
            app.FirFstopEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FpassEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.FpassEditFieldLabel.Text = 'Fpass';
            app.FirFpassEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FsEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FirFsEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FirFrequencyUnit = uidropdown(app.FilterDesignerPanel);
            app.FirFrequencyUnit.Items = {'Hz', 'KHz', 'MHz'};
            app.FirFrequencyUnit.Value = 'MHz';
            app.OrderEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.OrderEditFieldLabel.Enable = 'off';
            app.OrderEditFieldLabel.Text = 'Order';
            app.FirOrderEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FirOrderEditField.Editable = 'off';
            app.FirOrderEditField.Enable = 'off';
            app.FirOrderEditField.Value = 128;
            app.FirDesignButton = uibutton(app.FilterDesignerPanel, 'push');
            app.FirDesignButton.ButtonPushedFcn = createCallbackFcn(app, @FirDesignBtn, true);
            app.FirDesignButton.Text = 'Design';
            app.FirResponseType = uidropdown(app.FilterDesignerPanel);
            app.FirResponseType.Items = {'lowpassfir', 'lowpassiir', 'highpassfir', 'highpassiir', 'bandpassfir', 'bandpassiir', 'bandstopfir', 'bandstopiir', 'differentiatorfir', 'hilbertfir', 'arbmagfir'};
            app.FirResponseType.Value = 'lowpassfir';
            app.FirAmplitudeUnit = uidropdown(app.FilterDesignerPanel);
            app.FirAmplitudeUnit.Items = {'dB'};
            app.FirAmplitudeUnit.Enable = 'off';
            app.FirAmplitudeUnit.Value = 'dB';
            app.Tx1Tab = uitab(app.TabGroup);
            app.Tx1Tab.Title = 'Tx1';
            app.Tx1Plot = uiaxes(app.Tx1Tab);
            title(app.Tx1Plot, ' ')
            xlabel(app.Tx1Plot, 'X')
            ylabel(app.Tx1Plot, 'Y')
            zlabel(app.Tx1Plot, 'Z')
            app.Tx1SettingsTable = uitable(app.Tx1Tab);
            app.Tx1SettingsTable.ColumnName = '';
            app.Tx1SettingsTable.RowName = {};
            app.Tx1SettingsTable.ColumnEditable = true;
            app.Tx1SettingsTable.CellEditCallback = createCallbackFcn(app, @Tx1CellEdit, true);
            app.Tx1PlotConfig = uibuttongroup(app.Tx1Tab);
            app.Tx1PlotConfig.Title = 'Plot Configuration';
            app.Dpd1SpectrumButton = uiradiobutton(app.Tx1PlotConfig);
            app.Dpd1SpectrumButton.Text = 'Spectrum';
            app.Dpd1SpectrumButton.Value = true;
            app.Dpd1IQButton = uiradiobutton(app.Tx1PlotConfig);
            app.Dpd1IQButton.Text = 'IQ';
            app.Dpd1AMAMButton = uiradiobutton(app.Tx1PlotConfig);
            app.Dpd1AMAMButton.Text = 'AM/AM';
            app.Dpd1AMPMButton = uiradiobutton(app.Tx1PlotConfig);
            app.Dpd1AMPMButton.Text = 'AM/PM';
            app.Dpd1RxTxPhaseButton = uiradiobutton(app.Tx1PlotConfig);
            app.Dpd1RxTxPhaseButton.Text = 'RxTx Phase';
            app.Dpd1SubPlotsButton = uiradiobutton(app.Tx1PlotConfig);
            app.Dpd1SubPlotsButton.Text = 'Sub Plots';
            app.RadioStateDropDownLabel_3 = uilabel(app.Tx1Tab);
            app.RadioStateDropDownLabel_3.Text = 'Radio State';
            app.Tx1RadioState = uidropdown(app.Tx1Tab);
            app.Tx1RadioState.Items = {'Standby', 'Calibrated', 'Primed', 'Enabled'};
            app.Tx1RadioState.ValueChangedFcn = createCallbackFcn(app, @Tx1RadioStateChange, true);
            app.Tx1RadioState.Value = 'Standby';
            app.PhyStreamPanel = uipanel(app.Tx1Tab);
            app.PhyStreamPanel.Title = 'PhyStream';
            app.DataSourceLabel = uilabel(app.PhyStreamPanel);
            app.DataSourceLabel.Text = 'Data Source';
            app.Tx1DataSource = uidropdown(app.PhyStreamPanel);
            app.Tx1DataSource.Items = {'File', 'Zeros'};
            app.Tx1DataSource.ValueChangedFcn = createCallbackFcn(app, @Tx1DataSrcDropDown, true);
            app.Tx1DataSource.Value = 'File';
            app.Tx1Continuous = uicheckbox(app.PhyStreamPanel);
            app.Tx1Continuous.Text = 'Continuous';
            app.Tx1EnableStream = uicheckbox(app.PhyStreamPanel);
            app.Tx1EnableStream.ValueChangedFcn = createCallbackFcn(app, @Tx1EnableStreamChceckBox, true);
            app.Tx1EnableStream.Text = 'Enable';
            app.Tx2Tab = uitab(app.TabGroup);
            app.Tx2Tab.Title = 'Tx2';
            app.Tx2Plot = uiaxes(app.Tx2Tab);
            title(app.Tx2Plot, ' ')
            xlabel(app.Tx2Plot, 'X')
            ylabel(app.Tx2Plot, 'Y')
            zlabel(app.Tx2Plot, 'Z')
            app.Tx2SettingsTable = uitable(app.Tx2Tab);
            app.Tx2SettingsTable.ColumnName = '';
            app.Tx2SettingsTable.RowName = {};
            app.Tx2SettingsTable.ColumnEditable = true;
            app.Tx2SettingsTable.CellEditCallback = createCallbackFcn(app, @Tx2CellEdit, true);
            app.Tx2PlotConfig = uibuttongroup(app.Tx2Tab);
            app.Tx2PlotConfig.Title = 'Plot Configuration';
            app.Dpd2SpectrumButton = uiradiobutton(app.Tx2PlotConfig);
            app.Dpd2SpectrumButton.Text = 'Spectrum';
            app.Dpd2SpectrumButton.Value = true;
            app.Dpd2IQButton = uiradiobutton(app.Tx2PlotConfig);
            app.Dpd2IQButton.Text = 'IQ';
            app.Dpd2AMAMButton = uiradiobutton(app.Tx2PlotConfig);
            app.Dpd2AMAMButton.Text = 'AM/AM';
            app.Dpd2AMPMButton = uiradiobutton(app.Tx2PlotConfig);
            app.Dpd2AMPMButton.Text = 'AM/PM';
            app.Dpd2RxTxPhaseButton = uiradiobutton(app.Tx2PlotConfig);
            app.Dpd2RxTxPhaseButton.Text = 'RxTx Phase';
            app.Dpd2SubPlotsButton = uiradiobutton(app.Tx2PlotConfig);
            app.Dpd2SubPlotsButton.Text = 'Sub Plots';
            app.RadioStateDropDownLabel_4 = uilabel(app.Tx2Tab);
            app.RadioStateDropDownLabel_4.Text = 'Radio State';
            app.Tx2RadioState = uidropdown(app.Tx2Tab);
            app.Tx2RadioState.Items = {'Standby', 'Calibrated', 'Primed', 'Enabled'};
            app.Tx2RadioState.ValueChangedFcn = createCallbackFcn(app, @Tx2RadioStateChange, true);
            app.Tx2RadioState.Value = 'Standby';
            app.Rx1Tab = uitab(app.TabGroup);
            app.Rx1Tab.Title = 'Rx1';
            app.Rx1Plot = uiaxes(app.Rx1Tab);
            title(app.Rx1Plot, ' ')
            xlabel(app.Rx1Plot, 'X')
            ylabel(app.Rx1Plot, 'Y')
            zlabel(app.Rx1Plot, 'Z')
            app.Rx1SettingsTable = uitable(app.Rx1Tab);
            app.Rx1SettingsTable.ColumnName = '';
            app.Rx1SettingsTable.RowName = {};
            app.Rx1SettingsTable.ColumnEditable = true;
            app.Rx1PlotConfig = uibuttongroup(app.Rx1Tab);
            app.Rx1PlotConfig.Title = 'Plot Configuration';
            app.Rx1SpectrumButton = uiradiobutton(app.Rx1PlotConfig);
            app.Rx1SpectrumButton.Text = 'Spectrum';
            app.Rx1SpectrumButton.Value = true;
            app.Rx1IQButton = uiradiobutton(app.Rx1PlotConfig);
            app.Rx1IQButton.Text = 'IQ';
            app.Rx1SubPlotsButton = uiradiobutton(app.Rx1PlotConfig);
            app.Rx1SubPlotsButton.Text = 'Sub Plots';
            app.RadioStateDropDownLabel = uilabel(app.Rx1Tab);
            app.RadioStateDropDownLabel.Text = 'Radio State';
            app.Rx1RadioState = uidropdown(app.Rx1Tab);
            app.Rx1RadioState.Items = {'Standby', 'Calibrated', 'Primed', 'Enabled'};
            app.Rx1RadioState.Value = 'Standby';
            app.Rx2Tab = uitab(app.TabGroup);
            app.Rx2Tab.Title = 'Rx2';
            app.Rx2Plot = uiaxes(app.Rx2Tab);
            title(app.Rx2Plot, ' ')
            xlabel(app.Rx2Plot, 'X')
            ylabel(app.Rx2Plot, 'Y')
            zlabel(app.Rx2Plot, 'Z')
            app.Rx2SettingsTable = uitable(app.Rx2Tab);
            app.Rx2SettingsTable.ColumnName = '';
            app.Rx2SettingsTable.RowName = {};
            app.Rx2SettingsTable.ColumnEditable = true;
            app.Rx2PlotConfig = uibuttongroup(app.Rx2Tab);
            app.Rx2PlotConfig.Title = 'Plot Configuration';
            app.Rx2SpectrumButton = uiradiobutton(app.Rx2PlotConfig);
            app.Rx2SpectrumButton.Text = 'Spectrum';
            app.Rx2SpectrumButton.Value = true;
            app.Rx2IQButton = uiradiobutton(app.Rx2PlotConfig);
            app.Rx2IQButton.Text = 'IQ';
            app.Rx2SubPlotsButton = uiradiobutton(app.Rx2PlotConfig);
            app.Rx2SubPlotsButton.Text = 'Sub Plots';
            app.RadioStateDropDownLabel_2 = uilabel(app.Rx2Tab);
            app.RadioStateDropDownLabel_2.Text = 'Radio State';
            app.Rx2RadioState = uidropdown(app.Rx2Tab);
            app.Rx2RadioState.Items = {'Standby', 'Calibrated', 'Primed', 'Enabled'};
            app.Rx2RadioState.Value = 'Standby';
            app.LogText = uitextarea(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.ConnectButton = uibutton(app.Panel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectBtn, true);
            app.ConnectButton.Text = 'Connect';
            app.Logo = uiimage(app.Panel);
            app.Logo.ImageSource = 'Gold.jpg';
            app.IPAddressLabel = uilabel(app.Panel);
            app.IPAddressLabel.Text = 'IP Address';
            app.Address = uieditfield(app.Panel, 'text');
            app.Address.Value = '192.168.1.12';
            app.Logo_2 = uiimage(app.Panel);
            app.Logo_2.ImageSource = 'bytepipe.png';
            app.SerialPortLabel = uilabel(app.Panel);
            app.SerialPortLabel.Text = 'Serial Port';
            app.SerialPort = uidropdown(app.Panel);
            app.SerialPort.Items = {'COM3'};
            app.SerialPort.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortBtn, true);
            app.SerialPort.Value = 'COM3';
            app.TempCLabel = uilabel(app.Panel);
            app.TempCLabel.Text = 'Temp(C)';
            app.AdrvTemp = uieditfield(app.Panel, 'numeric');
            app.AdrvTemp.Editable = 'off';
            app.CommandEditFieldLabel = uilabel(app.UIFigure);
            app.CommandEditFieldLabel.Text = 'Command';
            app.CliCmdString = uieditfield(app.UIFigure, 'text');
            app.CliCmdString.Value = 'help';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @CommandSendBtn, true);
            app.SendButton.Text = 'Send';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBtn, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bytepipe_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end